classdef networkviewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        UIAxes           matlab.ui.control.UIAxes
        btn_loadnetwork  matlab.ui.control.Button
        uibuttongroup1   matlab.ui.container.ButtonGroup
        networkname      matlab.ui.control.Label
        linkcount        matlab.ui.control.Label
        nodecount        matlab.ui.control.Label
        text2            matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: btn_loadnetwork
        function btn_loadnetworkButtonPushed(app, event)
            [filename, filepath] = uigetfile({'*.inp'},'Load EPANET Inp File.');
            
            if ~isnumeric(filepath)
                cla( app.UIAxes )
            
                d=epanet([filepath, filename]);
                d.plot('axes', app.UIAxes, 'legend', 'hide');
            
                app.networkname.Text = ['File name: "', filename, '"'];
                app.linkcount.Text = ['Link count: ', num2str(d.getLinkCount)];
                app.nodecount.Text = ['Node count: ', num2str(d.getNodeCount)];
                %app.pipecount.Text = ['Pipe count: ', num2str(d.getLinkPipeCount)];
            
                d.unload % Unload network and libraries.
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.btn_loadnetwork = uibutton(app.UIFigure, 'push');
            app.btn_loadnetwork.ButtonPushedFcn = createCallbackFcn(app, @btn_loadnetworkButtonPushed, true);
            app.btn_loadnetwork.Text = 'Load Network';
            app.uibuttongroup1 = uibuttongroup(app.UIFigure);
            app.uibuttongroup1.Title = 'Summary';
            app.networkname = uilabel(app.uibuttongroup1);
            app.networkname.Text = '';
            app.linkcount = uilabel(app.uibuttongroup1);
            app.linkcount.Text = '';
            app.nodecount = uilabel(app.uibuttongroup1);
            app.nodecount.Text = '';
            app.text2 = uilabel(app.UIFigure);
            app.text2.Text = 'Network Viewer using EPANET MATLAB Toolkit';
        end
    end

    methods (Access = public)

        % Construct app
        function app = networkviewer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end